classdef Interfaz_FuncionalTerminada_V11 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Ventana                         matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        TablaPuertos                    matlab.ui.control.Table
        GridLayout12                    matlab.ui.container.GridLayout
        MuestrasSpinner                 matlab.ui.control.Spinner
        MuestrasSpinnerLabel            matlab.ui.control.Label
        RestablecerButton               matlab.ui.control.Button
        ActualizarButton                matlab.ui.control.Button
        ScreenShotButton                matlab.ui.control.Button
        FrecuenciaMuestreoSpinner       matlab.ui.control.Spinner
        FrecuenciaMuestreoSpinnerLabel  matlab.ui.control.Label
        GridLayout11                    matlab.ui.container.GridLayout
        CERRARButton                    matlab.ui.control.Button
        STOPButton                      matlab.ui.control.Button
        INICIOButton                    matlab.ui.control.Button
        OpcionesdegrficoPanel           matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        AutoescalaCheckBox              matlab.ui.control.CheckBox
        GridCheckBox                    matlab.ui.control.CheckBox
        InvertirCheckBox                matlab.ui.control.CheckBox
        CenterPanel                     matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        EMGTab                          matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        Grafico                         matlab.ui.control.UIAxes
        EMGFiltradaTab                  matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        GraficoEmgFiltrada              matlab.ui.control.UIAxes
        RectificacinTab                 matlab.ui.container.Tab
        GridLayout13                    matlab.ui.container.GridLayout
        GraficoRectificado              matlab.ui.control.UIAxes
        EnvolventeTab                   matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        GraficoEnvolvente               matlab.ui.control.UIAxes
        FFTTab                          matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        GraficoFFT                      matlab.ui.control.UIAxes
        ComparacinTab                   matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        GraficoFiltrada                 matlab.ui.control.UIAxes
        GraficoEMGEnvolvente            matlab.ui.control.UIAxes
        GraficoEMGRectificada           matlab.ui.control.UIAxes
        GraficoEMG                      matlab.ui.control.UIAxes
        MonitordeelectromiografaEMGentiemporealLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        RutaArchivoEditField            matlab.ui.control.EditField
        RutaArchivoEditFieldLabel       matlab.ui.control.Label
        IMPORTARButton                  matlab.ui.control.Button
        EXPORTARButton                  matlab.ui.control.Button
        LIMPIARButton                   matlab.ui.control.Button
        TablaDatos                      matlab.ui.control.Table
        ArchivoMenu                     matlab.ui.container.Menu
        ExportarMenu                    matlab.ui.container.Menu
        ImportarMenu                    matlab.ui.container.Menu
        SalirMenu                       matlab.ui.container.Menu
        AyudaMenu_2                     matlab.ui.container.Menu
        ManualMenu                      matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        PropiedadInicio % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global stop
            freeport = serialportlist("available");
            freeport = freeport';
            app.TablaPuertos.Data = freeport;
            
            stop = 1;
            app.STOPButton.BackgroundColor = [201 60 29]/255;
            app.INICIOButton.BackgroundColor = [0.96 0.96 0.96];
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.Ventana.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {703, 703, 703};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {703, 703};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {196, '1x', 289};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Cell selection callback: TablaPuertos
        function TablaPuertosCellSelection(app, event)
            global port
            try
                indices = event.Indices;
                row = indices(:,1);
                port = app.TablaPuertos.Data(row,:);
            catch 
                warndlg('Seleccione un único puerto serial, deberá seleccionar el puerto serial correspondiente al monitor de electromiografía','Excepción');
            end
        end

        % Button pushed function: ActualizarButton
        function ActualizarButtonPushed(app, event)
            try
                freeport = serialportlist("available");
                freeport = freeport';
                app.TablaPuertos.Data = freeport;
            catch 
                warndlg('No hay puertos seriales COM conectados','Excepción');
            end
        end

        % Button pushed function: INICIOButton
        function INICIOButtonPushed(app, event)
            global stop datos const port
            try  
            app.PropiedadInicio = serialport(port,115200,"Timeout",5);
            configureTerminator(app.PropiedadInicio,"CR/LF");
            stop = 0;

            app.TablaDatos.Data = [];
            app.INICIOButton.Enable = 'off';
            app.IMPORTARButton.Enable = 'off';
            app.EXPORTARButton.Enable = 'off';
            app.ImportarMenu.Enable = 'off';
            app.ExportarMenu.Enable = 'off';
            app.CERRARButton.Enable = 'off';
            app.SalirMenu.Enable = 'off';
            app.LIMPIARButton.Enable = 'off';
            app.MuestrasSpinner.Enable = 'off';
            app.FrecuenciaMuestreoSpinner.Enable = 'off';
            app.RestablecerButton.Enable = 'off';
            app.ActualizarButton.Enable = 'off';
            app.RutaArchivoEditField.Value = ' ';
            

            cla(app.GraficoEmgFiltrada);
            cla(app.GraficoFiltrada);
            cla(app.GraficoEmgFiltrada);
            cla(app.GraficoRectificado);
            cla(app.GraficoEnvolvente);
            cla(app.GraficoEMG);
            cla(app.GraficoEMGRectificada);
            cla(app.GraficoEMGEnvolvente);
            cla(app.GraficoFFT);
            
            muestras = app.MuestrasSpinner.Value;

            voltaje = 0;
            contador = 1;

            while contador <= muestras
               drawnow update;
               if stop == 1
                    app.INICIOButton.Enable = 'on';
                    app.IMPORTARButton.Enable = 'on';
                    app.EXPORTARButton.Enable = 'on';
                    app.ImportarMenu.Enable = 'on';
                    app.ExportarMenu.Enable = 'on';
                    app.CERRARButton.Enable = 'on';
                    app.SalirMenu.Enable = 'on';
                    app.LIMPIARButton.Enable = 'on';
                    app.MuestrasSpinner.Enable = 'on';
                    app.FrecuenciaMuestreoSpinner.Enable = 'on';
                    app.RestablecerButton.Enable = 'on';
                    app.ActualizarButton.Enable = 'on';
                    app.STOPButton.BackgroundColor = [201 60 29]/255;
                    app.INICIOButton.BackgroundColor = [0.96 0.96 0.96];
                    break;

               else
                    app.INICIOButton.BackgroundColor = [84 201 29]/255;
                    app.STOPButton.BackgroundColor = [0.96 0.96 0.96];
                    valorADC = str2double(readline(app.PropiedadInicio));

                    const(contador) = 5;
                    voltaje(contador) = valorADC(1)*5/308;
                    fq = app.FrecuenciaMuestreoSpinner.Value;
                    t = 1/fq*(1:length(voltaje));

                    plot(app.Grafico,t,voltaje);
                    plot(app.GraficoEMG,t,voltaje);
                    app.Grafico.XLim = [0 app.MuestrasSpinner.Value/fq];
                    app.Grafico.YLim = [0 100];
                    app.GraficoEMG.XLim = [0 app.MuestrasSpinner.Value/fq];
                    app.GraficoEMG.YLim = [0 100];
                    
                    tiempo(contador) = contador;
                    
                    datos = [tiempo/fq;voltaje];
                    p = datos';
                    app.TablaDatos.Data = p;
                   
                    contador = contador + 1;

                    SenalEMG = p(:,2);
                    
                    fft_SenalEMG = fft(SenalEMG);
                    fft_SenalEMG = fftshift(fft_SenalEMG);
                    n = length(SenalEMG);
                    fq_axis1 = (-n/2:n/2-1)*fq/n;
                    abs_fft_SenalEMG = abs(fft_SenalEMG);
                    
                    fq_pasaltos = 5;
                    fq_pasabajos = 10;
                                          
                    corte1 = ceil((12.5-fq_pasaltos)/(fq/length(SenalEMG))); corte2 = ceil((12.5-fq_pasabajos)/(fq/length(SenalEMG)));
                    corte3 = ceil((fq_pasaltos+12.5)/(fq/length(SenalEMG))); corte4 = ceil((fq_pasabajos+12.5)/(fq/length(SenalEMG)));
                    H = zeros(length(SenalEMG),1); H(corte2:corte1) = 1;
                    H(corte3:corte4) = 1;
                    
                    senal_filtrada = ifftshift(fft_SenalEMG.*H); 
                    senal_filtrada = ifft(senal_filtrada);
                    
                    plot(app.GraficoEmgFiltrada,t,senal_filtrada);
                    plot(app.GraficoFiltrada,t,senal_filtrada);
                    app.GraficoEmgFiltrada.XLim = [0 app.MuestrasSpinner.Value/fq];
                    app.GraficoEmgFiltrada.YLim = [-5 5];
                    app.GraficoFiltrada.XLim = [0 app.MuestrasSpinner.Value/fq];
                    app.GraficoFiltrada.YLim = [-5 5];
                    
                    EMG_Voltage=real(senal_filtrada);
                    RectifiedEMG=abs(EMG_Voltage);
                    RectifiedEMG=fillmissing(RectifiedEMG, 'constant', 0);
                    
                    [up] = envelope(RectifiedEMG,app.FrecuenciaMuestreoSpinner.Value/5,'peak');

                end
            end  

            app.STOPButton.BackgroundColor = [201 60 29]/255;
            app.INICIOButton.BackgroundColor = [0.96 0.96 0.96];
            stop = 1;
            delete(app.PropiedadInicio);
            app.INICIOButton.Enable = 'on';
            app.IMPORTARButton.Enable = 'on';
            app.EXPORTARButton.Enable = 'on';
            app.ImportarMenu.Enable = 'on';
            app.ExportarMenu.Enable = 'on';
            app.CERRARButton.Enable = 'on';
            app.SalirMenu.Enable = 'on';
            app.LIMPIARButton.Enable = 'on';
            app.MuestrasSpinner.Enable = 'on';
            app.FrecuenciaMuestreoSpinner.Enable = 'on';
            app.RestablecerButton.Enable = 'on';
            app.ActualizarButton.Enable = 'on';
            app.STOPButton.BackgroundColor = [201 60 29]/255;
            app.INICIOButton.BackgroundColor = [0.96 0.96 0.96];
            
          if stop == 1
                    plot(app.Grafico,voltaje);
                    plot(app.GraficoRectificado,t,RectifiedEMG);
                    plot(app.GraficoFFT,fq_axis1, abs_fft_SenalEMG); 
                    plot(app.GraficoEnvolvente,t,up);
                    plot(app.GraficoEMGRectificada,t,RectifiedEMG);
                    plot(app.GraficoEMGEnvolvente,t,up);
          end
            catch
                    warndlg('No se ha seleccionado un puerto serial','Excepción');
                    app.TablaDatos.Data = [];
                    cla(app.Grafico);
                    cla(app.GraficoEmgFiltrada);
                    cla(app.GraficoRectificado);
                    cla(app.GraficoEnvolvente);
                    cla(app.GraficoEMG);
                    cla(app.GraficoEMGRectificada);
                    cla(app.GraficoEMGEnvolvente);
                    cla(app.GraficoFFT);
                    app.INICIOButton.Enable = 'on';
                    app.IMPORTARButton.Enable = 'on';
                    app.EXPORTARButton.Enable = 'on';
                    app.ImportarMenu.Enable = 'on';
                    app.ExportarMenu.Enable = 'on';
                    app.CERRARButton.Enable = 'on';
                    app.SalirMenu.Enable = 'on';
                    app.LIMPIARButton.Enable = 'on';
                    app.MuestrasSpinner.Enable = 'on';
                    app.FrecuenciaMuestreoSpinner.Enable = 'on';
                    app.RestablecerButton.Enable = 'on';
                    app.ActualizarButton.Enable = 'on';
                    app.STOPButton.BackgroundColor = [201 60 29]/255;
                    app.INICIOButton.BackgroundColor = [0.96 0.96 0.96];
             end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
             global stop datos
             try
                    stop = 1;
                    Tablavacia = isempty(app.TablaDatos.Data);

              if Tablavacia == 1
                    app.TablaDatos.Data = zeros;
                    app.TablaDatos.Data = [];
              else
                    r = datos';
                    data1 = r;
                    fq = app.FrecuenciaMuestreoSpinner.Value;
                    SenalEMG = data1(:,2);
                    fft_SenalEMG = fft(SenalEMG);
                    fft_SenalEMG = fftshift(fft_SenalEMG);
                    n = length(SenalEMG);
                    fq_axis1 = (-n/2:n/2-1)*fq/n;
                    abs_fft_SenalEMG = abs(fft_SenalEMG);
                    plot(app.GraficoFFT,fq_axis1, abs_fft_SenalEMG); 
                    axis(app.GraficoFFT,[fq_axis1(1) fq_axis1(end) 0 1000]);
                    
                    fq_pasaltos = 5;
                    fq_pasabajos = 10;
                    
                    SenalEMG = data1(:,2); 
                
                    corte1 = ceil((12.5-fq_pasaltos)/(fq/length(SenalEMG))); corte2 = ceil((12.5-fq_pasabajos)/(fq/length(SenalEMG)));
                    corte3 = ceil((fq_pasaltos+12.5)/(fq/length(SenalEMG))); corte4 = ceil((fq_pasabajos+12.5)/(fq/length(SenalEMG)));
                    H = zeros(length(SenalEMG),1); H(corte2:corte1) = 1;                    H(corte3:corte4) = 1;
                    
                    senal_filtrada = ifftshift(fft_SenalEMG.*H); 
                    senal_filtrada = ifft(senal_filtrada);
                    t = 1/fq*(1:length(senal_filtrada));
                    plot(app.GraficoEmgFiltrada,t,senal_filtrada);
                    plot(app.GraficoFiltrada,t,senal_filtrada);
                    
                    EMG_Voltage=real(senal_filtrada);
                    RectifiedEMG=abs(EMG_Voltage);
                    RectifiedEMG=abs(RectifiedEMG);
                    RectifiedEMG=fillmissing(RectifiedEMG, 'constant', 0);
                    plot(app.GraficoRectificado,t,RectifiedEMG);
                                     
                    [up] = envelope(RectifiedEMG,app.FrecuenciaMuestreoSpinner.Value/5,'peak');
                    plot(app.GraficoEnvolvente,t,up);
                     
                    plot(app.GraficoEMGRectificada,t,RectifiedEMG);
                    plot(app.GraficoEMGEnvolvente,t,up);
              end
              
             catch
                    errordlg('El puerto COM no corresponde al monitor de electromiografía EMG oficial o posiblemente se encontró un posible error al intentar detener el sistema','Excepción / Error');
                    app.TablaDatos.Data = [];
                    app.TablaDatos.Data = zeros;
                    cla(app.Grafico);
                    cla(app.GraficoFiltrada);
                    cla(app.GraficoEmgFiltrada);
                    cla(app.GraficoRectificado);
                    cla(app.GraficoEnvolvente);
                    cla(app.GraficoEMG);
                    cla(app.GraficoEMGRectificada);
                    cla(app.GraficoEMGEnvolvente);
                    cla(app.GraficoFFT);
            end
        end

        % Button pushed function: CERRARButton
        function CERRARButtonPushed(app, event)
            delete(app.PropiedadInicio);
            app.delete;
        end

        % Close request function: Ventana
        function VentanaCloseRequest(app, event)
            delete(app.PropiedadInicio);
            delete(app)
        end

        % Button pushed function: EXPORTARButton
        function EXPORTARButtonPushed(app, event)
             global datos
             [filename,folder] = uiputfile('*.txt');
             file = fullfile(folder,filename);
             if filename == 0
                 return;
             else
                 h = rmmissing(datos');
                 writematrix(h,file);
                 app.RutaArchivoEditField.Value = file;
             end
        end

        % Value changed function: InvertirCheckBox
        function InvertirCheckBoxValueChanged(app, event)
            value = app.InvertirCheckBox.Value;
            if value == 1
                app.Grafico.YDir = 'reverse';
                app.GraficoEmgFiltrada.YDir = 'reverse';
                app.GraficoRectificado.YDir = 'reverse';
                app.GraficoEnvolvente.YDir = 'reverse';
                app.GraficoEMG.YDir = 'reverse';
                app.GraficoFiltrada.YDir = 'reverse';
                app.GraficoEMGRectificada.YDir = 'reverse';
                app.GraficoEMGEnvolvente.YDir = 'reverse';
                app.GraficoFFT.YDir = 'reverse';
            else
                app.Grafico.YDir = 'normal';
                app.GraficoEmgFiltrada.YDir = 'normal';
                app.GraficoRectificado.YDir = 'normal';
                app.GraficoEnvolvente.YDir = 'normal';
                app.GraficoEMG.YDir = 'normal';
                app.GraficoFiltrada.YDir = 'normal';
                app.GraficoEMGRectificada.YDir = 'normal';
                app.GraficoEMGEnvolvente.YDir = 'normal';
                app.GraficoFFT.YDir = 'normal';
            end
        end

        % Value changed function: GridCheckBox
        function GridCheckBoxValueChanged(app, event)
            value = app.GridCheckBox.Value;
            if value == 1
                app.Grafico.XGrid = 'on';
                app.Grafico.YGrid = 'on';
                app.GraficoEmgFiltrada.XGrid = 'on';
                app.GraficoEmgFiltrada.YGrid = 'on';
                app.GraficoRectificado.XGrid = 'on';
                app.GraficoRectificado.YGrid = 'on';
                app.GraficoEnvolvente.XGrid = 'on';
                app.GraficoEnvolvente.YGrid = 'on';
                app.GraficoEMG.XGrid = 'on';
                app.GraficoEMG.YGrid = 'on';
                app.GraficoFiltrada.XGrid = 'on';
                app.GraficoFiltrada.YGrid = 'on';
                app.GraficoEMGRectificada.XGrid = 'on';
                app.GraficoEMGRectificada.YGrid = 'on';
                app.GraficoEMGEnvolvente.XGrid = 'on';
                app.GraficoEMGEnvolvente.YGrid = 'on';
                app.GraficoFFT.YGrid = 'on';
            else
                app.Grafico.XGrid = 'off';
                app.Grafico.YGrid = 'off';
                app.GraficoEmgFiltrada.XGrid = 'off';
                app.GraficoEmgFiltrada.YGrid = 'off';
                app.GraficoRectificado.XGrid = 'off';
                app.GraficoRectificado.YGrid = 'off';
                app.GraficoEnvolvente.XGrid = 'off';
                app.GraficoEnvolvente.YGrid = 'off';
                app.GraficoEMG.XGrid = 'off';
                app.GraficoEMG.YGrid = 'off';
                app.GraficoFiltrada.XGrid = 'off';
                app.GraficoFiltrada.YGrid = 'off';
                app.GraficoEMGRectificada.XGrid = 'off';
                app.GraficoEMGRectificada.YGrid = 'off';
                app.GraficoEMGEnvolvente.XGrid = 'off';
                app.GraficoEMGEnvolvente.YGrid = 'off';
                app.GraficoFFT.YGrid = 'off';
            end
        end

        % Value changed function: AutoescalaCheckBox
        function AutoescalaCheckBoxValueChanged(app, event)
            value = app.AutoescalaCheckBox.Value;
            if value == 1
                app.Grafico.YLimMode = 'auto';
                app.Grafico.XLimMode = 'auto';
                app.GraficoEmgFiltrada.YLimMode = 'auto';
                app.GraficoEmgFiltrada.XLimMode = 'auto';
                app.GraficoRectificado.YLimMode = 'auto';
                app.GraficoRectificado.XLimMode = 'auto';
                app.GraficoEnvolvente.YLimMode = 'auto';
                app.GraficoEnvolvente.XLimMode = 'auto';
                app.GraficoFFT.YLimMode = 'auto';
                app.GraficoFFT.XLimMode = 'auto';
                app.GraficoEMG.YLimMode = 'auto';
                app.GraficoEMG.XLimMode = 'auto';
                app.GraficoFiltrada.YLimMode = 'auto';
                app.GraficoFiltrada.XLimMode = 'auto';
                app.GraficoEMGRectificada.YLimMode = 'auto';
                app.GraficoEMGRectificada.XLimMode = 'auto';
                app.GraficoEMGEnvolvente.YLimMode = 'auto';
                app.GraficoEMGEnvolvente.XLimMode = 'auto';
            else
                app.Grafico.YLimMode = 'manual';
                app.Grafico.XLimMode = 'manual';
                app.GraficoEmgFiltrada.YLimMode = 'manual';
                app.GraficoEmgFiltrada.XLimMode = 'manual';
                app.GraficoRectificado.YLimMode = 'manual';
                app.GraficoRectificado.XLimMode = 'manual';
                app.GraficoEnvolvente.YLimMode = 'manual';
                app.GraficoEnvolvente.XLimMode = 'manual';
                app.GraficoFFT.YLimMode = 'manual';
                app.GraficoFFT.XLimMode = 'manual';
                app.GraficoFiltrada.YLimMode = 'manual';
                app.GraficoFiltrada.XLimMode = 'manual';
                app.GraficoEMGRectificada.YLimMode = 'manual';
                app.GraficoEMGRectificada.XLimMode = 'manual';
                app.GraficoEMGEnvolvente.YLimMode = 'manual';
                app.GraficoEMGEnvolvente.XLimMode = 'manual';
            end
        end

        % Button pushed function: IMPORTARButton
        function IMPORTARButtonPushed(app, event)

            [filename,folder] = uigetfile('*.txt');
            file = fullfile(folder,filename);
            app.STOPButton.BackgroundColor = [0.96 0.96 0.96];
            app.INICIOButton.BackgroundColor = [0.96 0.96 0.96];
            
            if filename == 0
                return;
            else
                warning('off','MATLAB:plot:IgnoreImaginaryXYPart');
                app.STOPButton.BackgroundColor = [201 60 29]/255;
                app.RutaArchivoEditField.Value = file;
                t = readtable(file);
                app.TablaDatos.Data = t;
                t.Properties.VariableNames{1} = 'TIEMPO';
                t.Properties.VariableNames{2} = 'VOLTAJE';
                app.TablaDatos.ColumnName = t.Properties.VariableNames;

                x = t.(1);
                y = table2array(t(:,2:end));
                plot(app.Grafico,x,y);

                re = [x,y];
                r = rmmissing(re);
                data1 = r;
                fq = app.FrecuenciaMuestreoSpinner.Value;
                SenalEMG = data1(:,2);
                fft_SenalEMG = fft(SenalEMG);
                fft_SenalEMG = fftshift(fft_SenalEMG);
                n = length(SenalEMG);
                fq_axis1 = (-n/2:n/2-1)*fq/n;
                abs_fft_SenalEMG = abs(fft_SenalEMG);
                plot(app.GraficoFFT,fq_axis1, abs_fft_SenalEMG); 
                axis(app.GraficoFFT,[fq_axis1(1) fq_axis1(end) 0 1000]);
                
                fq_pasaltos = 5;
                fq_pasabajos = 10;
                         
                SenalEMG = data1(:,2); 
              
                corte1 = ceil((12.5-fq_pasaltos)/(fq/length(SenalEMG))); corte2 = ceil((12.5-fq_pasabajos)/(fq/length(SenalEMG)));
                corte3 = ceil((fq_pasaltos+12.5)/(fq/length(SenalEMG))); corte4 = ceil((fq_pasabajos+12.5)/(fq/length(SenalEMG)));
                H = zeros(length(SenalEMG),1); 
                H(corte2:corte1) = 1;                
                H(corte3:corte4) = 1; 
                
                senal_filtrada = ifftshift(fft_SenalEMG.*H); 
                senal_filtrada = ifft(senal_filtrada);
                plot(app.GraficoEmgFiltrada,x,senal_filtrada);
                plot(app.GraficoFiltrada,x,senal_filtrada);
                
                EMG_Voltage=real(senal_filtrada);
                RectifiedEMG=abs(EMG_Voltage);
                RectifiedEMG=fillmissing(RectifiedEMG, 'constant', 0);
                plot(app.GraficoRectificado,x,RectifiedEMG);
                               
                [up] = envelope(RectifiedEMG,app.FrecuenciaMuestreoSpinner.Value/5,'peak');
                plot(app.GraficoEnvolvente,x,up);
                
                plot(app.GraficoEMG,x,y);
                plot(app.GraficoEMGRectificada,x,RectifiedEMG);
                plot(app.GraficoEMGEnvolvente,x,up);
            end
        end

        % Button pushed function: LIMPIARButton
        function LIMPIARButtonPushed(app, event)
            app.TablaDatos.Data = zeros;
            app.TablaDatos.Data = [];
            cla(app.Grafico);
            cla(app.GraficoFiltrada);
            cla(app.GraficoEmgFiltrada);
            cla(app.GraficoRectificado);
            cla(app.GraficoEnvolvente);
            cla(app.GraficoEMG);
            cla(app.GraficoEMGRectificada);
            cla(app.GraficoEMGEnvolvente);
            cla(app.GraficoFFT);
            app.RutaArchivoEditField.Value = ' ';
            app.STOPButton.BackgroundColor = [201 60 29]/255;
            app.INICIOButton.BackgroundColor = [0.96 0.96 0.96];
        end

        % Menu selected function: ExportarMenu
        function ExportarMenuSelected(app, event)
             global datos
             [filename,folder] = uiputfile('*.txt');
             file = fullfile(folder,filename);
             if filename == 0
                 return;
             else
                 h = rmmissing(datos');
                 writematrix(h,file);
                 app.RutaArchivoEditField.Value = file;
             end
        end

        % Menu selected function: ImportarMenu
        function ImportarMenuSelected(app, event)
             [filename,folder] = uigetfile('*.txt');

             file = fullfile(folder,filename);
             app.RutaArchivoEditField.Value = file;
             app.STOPButton.BackgroundColor = [0.96 0.96 0.96];
             app.INICIOButton.BackgroundColor = [0.96 0.96 0.96];

             if filename == 0
                return;
             else
                warning('off','MATLAB:plot:IgnoreImaginaryXYPart');
                app.STOPButton.BackgroundColor = [201 60 29]/255;
                t = readtable(file);
                app.TablaDatos.Data = t;
                t.Properties.VariableNames{1} = 'TIEMPO';
                t.Properties.VariableNames{2} = 'VOLTAJE';
                app.TablaDatos.ColumnName = t.Properties.VariableNames;
                x = t.(1);
                y = table2array(t(:,2:end));
                plot(app.Grafico,x,y);
                
                
                re = [x,y];
                r = rmmissing(re);
                data1 = r;
                fq = app.FrecuenciaMuestreoSpinner.Value;
                SenalEMG = data1(:,2);
                fft_SenalEMG = fft(SenalEMG);
                fft_SenalEMG = fftshift(fft_SenalEMG);
                n = length(SenalEMG);
                fq_axis1 = (-n/2:n/2-1)*fq/n;
                abs_fft_SenalEMG = abs(fft_SenalEMG);
                plot(app.GraficoFFT,fq_axis1, abs_fft_SenalEMG); 
                axis(app.GraficoFFT,[fq_axis1(1) fq_axis1(end) 0 1000]);
                
                fq_pasaltos = 5;
                fq_pasabajos = 10;
                             
                SenalEMG = data1(:,2); 
               
                corte1 = ceil((12.5-fq_pasaltos)/(fq/length(SenalEMG))); corte2 = ceil((12.5-fq_pasabajos)/(fq/length(SenalEMG)));
                corte3 = ceil((fq_pasaltos+12.5)/(fq/length(SenalEMG))); corte4 = ceil((fq_pasabajos+12.5)/(fq/length(SenalEMG)));
                H = zeros(length(SenalEMG),1); 
                H(corte2:corte1) = 1;
                H(corte3:corte4) = 1;
                
                senal_filtrada = ifftshift(fft_SenalEMG.*H); 
                senal_filtrada = ifft(senal_filtrada);
                plot(app.GraficoEmgFiltrada,x,senal_filtrada);
                plot(app.GraficoFiltrada,x,senal_filtrada);
 
                EMG_Voltage=real(senal_filtrada);
                RectifiedEMG=abs(EMG_Voltage);
                RectifiedEMG=fillmissing(RectifiedEMG, 'constant', 0);
                plot(app.GraficoRectificado,x,RectifiedEMG);
                
                [up] = envelope(RectifiedEMG,app.FrecuenciaMuestreoSpinner.Value/5,'peak');
                plot(app.GraficoEnvolvente,x,up);
                                
                plot(app.GraficoEMG,x,y);
                plot(app.GraficoEMGRectificada,x,RectifiedEMG);
                plot(app.GraficoEMGEnvolvente,x,up);
             end
        end

        % Menu selected function: ManualMenu
        function ManualMenuSelected(app, event)
            open("Manual_Interfaz\DocumentoAyuda.pdf");
        end

        % Menu selected function: SalirMenu
        function SalirMenuSelected(app, event)
            delete(app.PropiedadInicio);
            app.delete;
        end

        % Button pushed function: ScreenShotButton
        function ScreenShotButtonPushed(app, event)
            filter = {'*.jpg';'*.png';'*.tif';'*.pdf'};
            [filename,filepath] = uiputfile(filter);
            if app.TabGroup.SelectedTab == app.EMGTab
                tabla = app.Grafico;
            elseif app.TabGroup.SelectedTab == app.EMGFiltradaTab
                tabla = app.GraficoEmgFiltrada;
            elseif app.TabGroup.SelectedTab == app.RectificacinTab
                tabla = app.GraficoRectificado;
            elseif app.TabGroup.SelectedTab == app.EnvolventeTab
                tabla = app.GraficoEnvolvente;
            elseif app.TabGroup.SelectedTab == app.FFTTab
                tabla = app.GraficoFFT;
            elseif app.TabGroup.SelectedTab == app.ComparacinTab
                tabla = app.GridLayout8;
            end
            if ischar(filename)
                  exportgraphics(tabla,[filepath filename]);
            end
        end

        % Button pushed function: RestablecerButton
        function RestablecerButtonPushed(app, event)
            app.MuestrasSpinner.Value = 300;
            app.FrecuenciaMuestreoSpinner.Value = 25;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Ventana = uifigure('Visible', 'off');
            app.Ventana.Name = 'MATLAB App';
            app.Ventana.CloseRequestFcn = createCallbackFcn(app, @VentanaCloseRequest, true);
            app.Ventana.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.Ventana);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.OpcionesdegrficoPanel = uipanel(app.GridLayout2);
            app.OpcionesdegrficoPanel.Title = 'Opciones de gráfico';
            app.GridLayout10 = uigridlayout(app.OpcionesdegrficoPanel);
            app.InvertirCheckBox = uicheckbox(app.GridLayout10);
            app.InvertirCheckBox.ValueChangedFcn = createCallbackFcn(app, @InvertirCheckBoxValueChanged, true);
            app.InvertirCheckBox.Text = 'Invertir';
            app.GridCheckBox = uicheckbox(app.GridLayout10);
            app.GridCheckBox.ValueChangedFcn = createCallbackFcn(app, @GridCheckBoxValueChanged, true);
            app.GridCheckBox.Text = 'Grid';
            app.AutoescalaCheckBox = uicheckbox(app.GridLayout10);
            app.AutoescalaCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoescalaCheckBoxValueChanged, true);
            app.AutoescalaCheckBox.Text = 'Autoescala';
            app.AutoescalaCheckBox.Value = true;
            app.GridLayout11 = uigridlayout(app.GridLayout2);
            app.INICIOButton = uibutton(app.GridLayout11, 'push');
            app.INICIOButton.ButtonPushedFcn = createCallbackFcn(app, @INICIOButtonPushed, true);
            app.INICIOButton.Text = 'INICIO';
            app.STOPButton = uibutton(app.GridLayout11, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.CERRARButton = uibutton(app.GridLayout11, 'push');
            app.CERRARButton.ButtonPushedFcn = createCallbackFcn(app, @CERRARButtonPushed, true);
            app.CERRARButton.Text = 'CERRAR';
            app.GridLayout12 = uigridlayout(app.GridLayout2);
            app.FrecuenciaMuestreoSpinnerLabel = uilabel(app.GridLayout12);
            app.FrecuenciaMuestreoSpinnerLabel.Text = 'Frecuencia Muestreo';
            app.FrecuenciaMuestreoSpinner = uispinner(app.GridLayout12);
            app.FrecuenciaMuestreoSpinner.Step = 5;
            app.FrecuenciaMuestreoSpinner.Limits = [25 100];
            app.FrecuenciaMuestreoSpinner.RoundFractionalValues = 'on';
            app.FrecuenciaMuestreoSpinner.ValueDisplayFormat = '%5.0f Hz';
            app.FrecuenciaMuestreoSpinner.Value = 25;
            app.ScreenShotButton = uibutton(app.GridLayout12, 'push');
            app.ScreenShotButton.ButtonPushedFcn = createCallbackFcn(app, @ScreenShotButtonPushed, true);
            app.ScreenShotButton.Text = 'ScreenShot';
            app.ActualizarButton = uibutton(app.GridLayout12, 'push');
            app.ActualizarButton.ButtonPushedFcn = createCallbackFcn(app, @ActualizarButtonPushed, true);
            app.ActualizarButton.Text = 'Actualizar';
            app.RestablecerButton = uibutton(app.GridLayout12, 'push');
            app.RestablecerButton.ButtonPushedFcn = createCallbackFcn(app, @RestablecerButtonPushed, true);
            app.RestablecerButton.Text = 'Restablecer';
            app.MuestrasSpinnerLabel = uilabel(app.GridLayout12);
            app.MuestrasSpinnerLabel.Text = 'Muestras';
            app.MuestrasSpinner = uispinner(app.GridLayout12);
            app.MuestrasSpinner.Limits = [1 100000];
            app.MuestrasSpinner.ValueDisplayFormat = '%5.0f Muestras';
            app.MuestrasSpinner.Value = 750;
            app.TablaPuertos = uitable(app.GridLayout2);
            app.TablaPuertos.ColumnName = {'Puerto Serial'};
            app.TablaPuertos.RowName = {};
            app.TablaPuertos.CellSelectionCallback = createCallbackFcn(app, @TablaPuertosCellSelection, true);
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.CenterPanel);
            app.MonitordeelectromiografaEMGentiemporealLabel = uilabel(app.GridLayout3);
            app.MonitordeelectromiografaEMGentiemporealLabel.Text = 'Monitor de electromiografía (EMG) en tiempo real';
            app.TabGroup = uitabgroup(app.GridLayout3);
            app.EMGTab = uitab(app.TabGroup);
            app.EMGTab.Title = 'EMG';
            app.GridLayout7 = uigridlayout(app.EMGTab);
            app.Grafico = uiaxes(app.GridLayout7);
            title(app.Grafico, 'EMG')
            xlabel(app.Grafico, 'Muestras')
            ylabel(app.Grafico, 'Amplitud (V)')
            zlabel(app.Grafico, 'Z')
            app.EMGFiltradaTab = uitab(app.TabGroup);
            app.EMGFiltradaTab.Title = 'EMG Filtrada';
            app.GridLayout6 = uigridlayout(app.EMGFiltradaTab);
            app.GraficoEmgFiltrada = uiaxes(app.GridLayout6);
            title(app.GraficoEmgFiltrada, 'EMG Filtrada')
            xlabel(app.GraficoEmgFiltrada, 'Tiempo (Segundos)')
            ylabel(app.GraficoEmgFiltrada, 'Amplitud (V)')
            zlabel(app.GraficoEmgFiltrada, 'Z')
            app.RectificacinTab = uitab(app.TabGroup);
            app.RectificacinTab.Title = 'Rectificación';
            app.GridLayout13 = uigridlayout(app.RectificacinTab);
            app.GraficoRectificado = uiaxes(app.GridLayout13);
            title(app.GraficoRectificado, 'EMG Rectificada ')
            xlabel(app.GraficoRectificado, 'Tiempo (Segundos)')
            ylabel(app.GraficoRectificado, 'Amplitud (V)')
            zlabel(app.GraficoRectificado, 'Z')
            app.EnvolventeTab = uitab(app.TabGroup);
            app.EnvolventeTab.Title = 'Envolvente';
            app.GridLayout5 = uigridlayout(app.EnvolventeTab);
            app.GraficoEnvolvente = uiaxes(app.GridLayout5);
            title(app.GraficoEnvolvente, 'Envolvente')
            xlabel(app.GraficoEnvolvente, 'Tiempo (Segundos)')
            ylabel(app.GraficoEnvolvente, 'Amplitud (V)')
            zlabel(app.GraficoEnvolvente, 'Z')
            app.FFTTab = uitab(app.TabGroup);
            app.FFTTab.Title = 'FFT';
            app.GridLayout9 = uigridlayout(app.FFTTab);
            app.GraficoFFT = uiaxes(app.GridLayout9);
            title(app.GraficoFFT, 'FFT')
            xlabel(app.GraficoFFT, 'Frecuencia (Hz)')
            ylabel(app.GraficoFFT, 'Amplitud')
            zlabel(app.GraficoFFT, 'Z')
            app.ComparacinTab = uitab(app.TabGroup);
            app.ComparacinTab.Title = 'Comparación';
            app.GridLayout8 = uigridlayout(app.ComparacinTab);
            app.GraficoEMG = uiaxes(app.GridLayout8);
            title(app.GraficoEMG, 'EMG')
            xlabel(app.GraficoEMG, 'Tiempo (Segundos)')
            ylabel(app.GraficoEMG, 'Amplitud (V)')
            zlabel(app.GraficoEMG, 'Z')
            app.GraficoEMGRectificada = uiaxes(app.GridLayout8);
            title(app.GraficoEMGRectificada, 'Rectificación EMG Filtrada ')
            xlabel(app.GraficoEMGRectificada, 'Tiempo (Segundos)')
            ylabel(app.GraficoEMGRectificada, 'Amplitud (V)')
            zlabel(app.GraficoEMGRectificada, 'Z')
            app.GraficoEMGEnvolvente = uiaxes(app.GridLayout8);
            title(app.GraficoEMGEnvolvente, 'Envolvente')
            xlabel(app.GraficoEMGEnvolvente, 'Tiempo (Segundos)')
            ylabel(app.GraficoEMGEnvolvente, 'Amplitud (V)')
            zlabel(app.GraficoEMGEnvolvente, 'Z')
            app.GraficoFiltrada = uiaxes(app.GridLayout8);
            title(app.GraficoFiltrada, 'EMG Filtrada')
            xlabel(app.GraficoFiltrada, 'Tiempo (Segundos)')
            ylabel(app.GraficoFiltrada, 'Amplitud (V)')
            zlabel(app.GraficoFiltrada, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.RightPanel);
            app.TablaDatos = uitable(app.GridLayout4);
            app.TablaDatos.ColumnName = {'TIEMPO'; 'VOLTAJE'};
            app.TablaDatos.RowName = {};
            app.LIMPIARButton = uibutton(app.GridLayout4, 'push');
            app.LIMPIARButton.ButtonPushedFcn = createCallbackFcn(app, @LIMPIARButtonPushed, true);
            app.LIMPIARButton.Text = 'LIMPIAR';
            app.EXPORTARButton = uibutton(app.GridLayout4, 'push');
            app.EXPORTARButton.ButtonPushedFcn = createCallbackFcn(app, @EXPORTARButtonPushed, true);
            app.EXPORTARButton.Text = 'EXPORTAR';
            app.IMPORTARButton = uibutton(app.GridLayout4, 'push');
            app.IMPORTARButton.ButtonPushedFcn = createCallbackFcn(app, @IMPORTARButtonPushed, true);
            app.IMPORTARButton.Text = 'IMPORTAR';
            app.RutaArchivoEditFieldLabel = uilabel(app.GridLayout4);
            app.RutaArchivoEditFieldLabel.Text = 'Ruta Archivo';
            app.RutaArchivoEditField = uieditfield(app.GridLayout4, 'text');
            app.ArchivoMenu = uimenu(app.Ventana);
            app.ArchivoMenu.Text = 'Archivo';
            app.ExportarMenu = uimenu(app.ArchivoMenu);
            app.ExportarMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportarMenuSelected, true);
            app.ExportarMenu.Text = 'Exportar';
            app.ImportarMenu = uimenu(app.ArchivoMenu);
            app.ImportarMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportarMenuSelected, true);
            app.ImportarMenu.Text = 'Importar';
            app.SalirMenu = uimenu(app.ArchivoMenu);
            app.SalirMenu.MenuSelectedFcn = createCallbackFcn(app, @SalirMenuSelected, true);
            app.SalirMenu.Text = 'Salir';
            app.AyudaMenu_2 = uimenu(app.Ventana);
            app.AyudaMenu_2.Text = 'Ayuda';
            app.ManualMenu = uimenu(app.AyudaMenu_2);
            app.ManualMenu.MenuSelectedFcn = createCallbackFcn(app, @ManualMenuSelected, true);
            app.ManualMenu.Text = 'Manual';
            % Show the figure after all components are created
            app.Ventana.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz_FuncionalTerminada_V11

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Ventana)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Ventana)
        end
    end
end